classdef EditableTable < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout          matlab.ui.container.GridLayout
        ChangesStatusLabel  matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        AddButton           matlab.ui.control.Button
        RemoveButton        matlab.ui.control.Button
        MoveUpButton        matlab.ui.control.Button
        MoveDownButton      matlab.ui.control.Button
        ApplyButton         matlab.ui.control.Button
        RejectButton        matlab.ui.control.Button
        UITable             matlab.ui.control.Table
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        addbuttonpressed
        rangeswitched
    end

    properties (Access = private)
        columnValues double {mustBeInteger(columnValues)} = [1,2,4,6];
        disableRangeCounter (1,1) double {mustBeInteger(disableRangeCounter)} = 0; % used to automatically disable range after one other edit field in an app turns range on.
        disabledStyle (1,1)
        enabledStyle (1,1)
        fraction2Column double
        fractionColumn double
        nRows (1,1) double = 0;
        nameColumn
        rangedColumn logical
        rangedIndex (1,1) double {mustBeInteger(rangedIndex)} = 0;
        ratio2Column double
        ratioColumn double
        symbolArray
        symbolColumn
        unitArray
        unitColumn Unit
        unitIndexColumn double {mustBeInteger(unitIndexColumn)}
    end
    
    properties (Access = public)
        canBeRanged logical = true;
        hasNameWhitelist logical = true;
        nameColumn_applied
        nameWhitelist string % if whitelist isn't active, this acts as a default name
        rangedIndex_applied (1,1) double {mustBeInteger(rangedIndex_applied)} = 0;
        ratio2Column_applied double
        ratioColumn_applied double
        symbolColumn_applied
    end
    
    methods (Access = public)
        
        function unitType = unitType(comp)
            unitType = "ratio";
        end
        
        function resetup(comp)
            comp.postSetupFcn;
        end

        function addrow(comp, newName, newRatio, newSymbol)
            comp.nRows = (size(comp.ratioColumn, 2) + 1);

            comp.updatenamecolumn(comp.nRows, newName);
            comp.updateratiocolumn(comp.nRows, newRatio);
            comp.updatesymbolcolumn(comp.nRows, newSymbol);
            if comp.canBeRanged
                comp.updateratio2column(comp.nRows, newRatio);
            end

            comp.updatetabledisplay;
        end

        function testfun(comp)
            rows = comp.nRows;
            name = comp.nameColumn;
            ratio = comp.ratioColumn;
            ratio2 = comp.ratio2Column;
            fraction = comp.fractionColumn;
            fraction2 = comp.fraction2Column;
            unit = comp.unitColumn;
            symbol = comp.symbolColumn;
            rangedIndex = comp.rangedIndex;
            name_applied = comp.nameColumn_applied;
            ratio_applied = comp.ratioColumn_applied;
            ratio2_applied = comp.ratio2Column_applied;
            fraction_applied = comp.fractionColumn;
            fraction2_applied = comp.fraction2Column;
            unit_applied = comp.unitColumn;
            symbol_applied = comp.symbolColumn_applied;
            rangedIndex_applied = comp.rangedIndex_applied;
        end

        function teststart(comp)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitTypes().tostr(unit.gettype);
                    unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(comp, unitArray.ratio);
        end

        function removerow(comp)
            rows = comp.UITable.Selection;
            if ~isempty(rows)

                comp.nameColumn(rows) = [];
                comp.ratioColumn(rows) = [];
                comp.unitColumn(rows) = [];
                comp.symbolColumn(rows) = [];
                comp.unitIndexColumn(rows) = [];

                if comp.canBeRanged
                    comp.ratio2Column(rows) = [];
                    if ismember(comp.rangedIndex, rows)
                        comp.rangedIndex = 0;
                    end
                end

                comp.nRows = (size(comp.ratioColumn, 2));
                comp.updatetabledisplay;
            end
        end

        function moveuprow(comp)
            selected = comp.UITable.Selection;
            if ~isempty(selected) && min(selected) > 1

                for i = min(selected) - 1 : 1 : max(selected)
                    if ismember(i, selected)
                        comp.nameColumn([i-1, i]) = comp.nameColumn([i, i-1]);
                        comp.ratioColumn([i-1, i]) = comp.ratioColumn([i, i-1]);
                        comp.unitColumn([i-1, i]) = comp.unitColumn([i, i-1]);
                        comp.symbolColumn([i-1, i]) = comp.symbolColumn([i, i-1]);
                        comp.unitIndexColumn([i-1, i]) = comp.unitIndexColumn([i, i-1]);
                        if comp.canBeRanged
                            comp.ratio2Column([i-1, i]) = comp.ratio2Column([i, i-1]);
                            if comp.rangedIndex == i
                                comp.rangedIndex = comp.rangedIndex - 1;
                            elseif comp.rangedIndex == i - 1
                                comp.rangedIndex = comp.rangedIndex + 1;
                            end
                        end
                    end
                end
                comp.UITable.Selection = comp.UITable.Selection - 1;
                updatetabledisplay(comp);
            end
        end

        function movedownrow(comp)
            selected = comp.UITable.Selection;
            if ~isempty(selected) && max(selected) < comp.nRows
                for i = max(selected) + 1 : - 1 : min(selected)
                    if ismember(i, selected)
                        comp.nameColumn([i+1, i]) = comp.nameColumn([i, i+1]);
                        comp.ratioColumn([i+1, i]) = comp.ratioColumn([i, i+1]);
                        comp.unitColumn([i+1, i]) = comp.unitColumn([i, i+1]);
                        comp.symbolColumn([i+1, i]) = comp.symbolColumn([i, i+1]);
                        comp.unitIndexColumn([i+1, i]) = comp.unitIndexColumn([i, i+1]);
                        if comp.canBeRanged
                            comp.ratio2Column([i+1, i]) = comp.ratio2Column([i, i+1]);
                            if comp.rangedIndex == i
                                comp.rangedIndex = comp.rangedIndex + 1;
                            elseif comp.rangedIndex == i + 1
                                comp.rangedIndex = comp.rangedIndex - 1;
                            end
                        end
                    end
                end
                comp.UITable.Selection = comp.UITable.Selection + 1;
                updatetabledisplay(comp);
            end
        end

        function applychanges(comp)
            comp.nameColumn_applied = comp.nameColumn;
            comp.ratioColumn_applied = comp.ratioColumn;
            comp.ratio2Column_applied = comp.ratio2Column;
            comp.symbolColumn_applied = comp.symbolColumn;
            if comp.canBeRanged
                comp.rangedIndex_applied = comp.rangedIndex;
            end
            comp.updatetabledisplay;
        end

        function rejectchanges(comp)
            comp.nameColumn = [];
            comp.ratioColumn = [];
            comp.ratio2Column = [];
            comp.unitColumn = Unit.empty;
            comp.symbolColumn = [];
            comp.unitIndexColumn = [];

            if all([comp.canBeRanged, comp.disableRangeCounter ~= 0])
                comp.rangedIndex = comp.rangedIndex_applied;
            end

            comp.nRows = (size(comp.ratioColumn_applied, 2));
            comp.ratioColumn = comp.ratioColumn_applied;
            comp.ratio2Column = comp.ratio2Column_applied;

            for i = 1 : size(comp.ratioColumn, 2)
                updatenamecolumn(comp, i, comp.nameColumn_applied(i));
                updatesymbolcolumn(comp, i, comp.symbolColumn_applied(i));
            end
            comp.updatetabledisplay;
        end

        function updateunits(comp, unitArray)
            comp.unitArray = unitArray;
            comp.symbolArray = string.empty;
            for unit = unitArray
                comp.symbolArray(end + 1) = convertCharsToStrings(unit.getsymbol);
            end
        end

        function disablerange(comp)
            if comp.disableRangeCounter == 1
                comp.disableRangeCounter = 0;
                comp.rangedIndex = 0;
            elseif comp.disableRangeCounter == 2
                comp.disableRangeCounter = 1;
            end
            comp.updatetabledisplay
        end
    end

    methods (Access = private)

        function updatenamecolumn(comp, rows, newName)

            newName = string(newName)

            if comp.hasNameWhitelist
                index = find(comp.nameWhitelist == newName, 1);
                if isempty(index)
                    index = 1;
                end
                if isempty(comp.nameColumn)
                    comp.nameColumn = categorical.empty;
                    comp.nameColumn = setcats(comp.nameColumn, comp.nameWhitelist);
                end
                comp.nameColumn(rows) = newName;
            else
                if isempty(comp.nameColumn)
                    comp.nameColumn = string.empty;
                end
                comp.testfun
                if strlength(newName) > 0
                    comp.nameColumn(rows) = newName;
                else
                    comp.nameColumn(rows) = comp.nameWhitelist(1) + comp.nRows;
                end
            end
        end

        function updateratiocolumn(comp, rows, newRatio)
            if isnumeric(newRatio)
                comp.ratioColumn(rows) = newRatio;
            end
        end

        function updateratio2column(comp, rows, newRatio)
            if isnumeric(newRatio)
                comp.ratio2Column(rows) = newRatio;
            end
        end

        function updatefractioncolumn(comp)
            comp.fractionColumn = [];
            comp.fraction2Column = [];
            denominator1 = sum(comp.ratioColumn);
            for i = 1 : comp.nRows
                comp.fractionColumn(i) = comp.unitColumn(i).apply(comp.ratioColumn(i) / denominator1);
            end
            if comp.rangedIndex ~= 0
                denominator2 = sum(comp.ratioColumn) - comp.ratioColumn(comp.rangedIndex) + comp.ratio2Column(comp.rangedIndex);
                for i = 1 : comp.nRows
                    if i == comp.rangedIndex
                        comp.fraction2Column(i) = comp.unitColumn(i).apply(comp.ratio2Column(i) / denominator2);
                    else
                        comp.fraction2Column(i) = comp.unitColumn(i).apply(comp.ratioColumn(i) / denominator2);
                    end
                end
            elseif comp.canBeRanged
                comp.fraction2Column = comp.fractionColumn;
            end
        end

        function updatesymbolcolumn(comp, rows, newSymbol)
            newSymbol = string(newSymbol);
            index = find(comp.symbolArray == newSymbol, 1);
            if isempty(index)
                index = 1;
            end
            comp.unitIndexColumn(rows) = index;
            unit = comp.unitArray(index);
            comp.unitColumn(rows) = unit;

            if isempty(comp.symbolColumn)
                comp.symbolColumn = categorical.empty;
                comp.symbolColumn = setcats(comp.symbolColumn, comp.symbolArray);
            end

            comp.symbolColumn(rows) = newSymbol;
        end

        function updaterangedcolumn(comp)
            comp.rangedColumn = zeros(1, comp.nRows);
            if comp.rangedIndex ~= 0
                comp.rangedColumn(comp.rangedIndex) = true;
            end
        end

        function updatetabledisplay(comp)
            if isempty(comp.nameColumn)
                if comp.hasNameWhitelist
                    comp.nameColumn = categorical.empty;
                else
                    comp.nameColumn = string.empty;
                end
            end

            comp.updatefractioncolumn;
            comp.updaterangedcolumn;
            comp.checkforchanges;
            if comp.canBeRanged
                comp.UITable.Data = table(comp.nameColumn', comp.ratioColumn', comp.ratio2Column', ...
                    comp.fractionColumn', comp.fraction2Column', comp.symbolColumn', comp.rangedColumn');
                addStyle(comp.UITable, comp.disabledStyle, 'column', 3);
                if comp.rangedIndex ~= 0
                    addStyle(comp.UITable, comp.enabledStyle, 'cell', [comp.rangedIndex, 3]);
                end
            else
                comp.UITable.Data = table(comp.nameColumn', comp.ratioColumn', comp.fractionColumn', comp.symbolColumn');
            end
        end

        function checkforchanges(comp)
            comp.testfun
            if size(comp.nameColumn, 2) ~= size(comp.nameColumn_applied, 2)
                comp.Lamp.Color = [1, 0 ,0];
                comp.ChangesStatusLabel.Text = "There are changes to apply";
            elseif all([comp.nameColumn == comp.nameColumn_applied, comp.ratioColumn == comp.ratioColumn_applied, comp.symbolColumn == comp.symbolColumn_applied])
                comp.Lamp.Color = [0, 1, 0];
                comp.ChangesStatusLabel.Text = "No new changes to apply";
            else
                comp.Lamp.Color = [1, 0 ,0];
                comp.ChangesStatusLabel.Text = "There are changes to apply";
            end
        end

        function rangedCheckboxClicked(comp, rows, isRanged)
            if isRanged == true
                comp.disableRangeCounter = 2;
                notify(comp, 'rangeswitched');
                comp.rangedIndex = min(rows);
            else ismember(comp.rangedIndex, rows)
                comp.rangedIndex = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.disabledStyle = uistyle("BackgroundColor", [0.95, 0.95, 0.95]);
            comp.enabledStyle = uistyle("BackgroundColor", [1, 1, 1]);
            
            if comp.canBeRanged
                comp.UITable.ColumnName = ["Name", "Ratio from", "to", "Fraction from", "to", "Unit", "Ranged?"];
                comp.UITable.ColumnWidth = {"1x", "1x", "1x", "1x", "1x", 40, 70};
                comp.UITable.ColumnEditable = [true, true, true, false, false, true, true];
                comp.columnValues = [1,2,3,4,5,6,7];
                addStyle(comp.UITable, comp.disabledStyle, 'column', [3, 4, 5]);
                addStyle(comp.UITable, comp.enabledStyle, 'column', [1, 2, 6, 7]);
            else
                comp.UITable.ColumnName = ["Name", "Ratio", "Fraction", "Unit"];
                comp.UITable.ColumnWidth = {"1x", "1x", "1x", 40};
                comp.UITable.ColumnEditable = [true, true, false, true];
                comp.columnValues = [1,2,4,6];
                addStyle(comp.UITable, comp.disabledStyle, 'column', 3);
                addStyle(comp.UITable, comp.enabledStyle, 'column', [1, 2, 4]);
            end

            if comp.hasNameWhitelist
                comp.nameColumn_applied = categorical.empty;
            else
                comp.nameColumn_applied = string.empty;
            end
            %comp.nameWhitelist = ["propellant"];
            %comp.teststart;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(comp, event)
            column = comp.columnValues(event.Indices(2));
            row = event.Indices(1);

            if column == 1
                comp.updatenamecolumn(row, event.NewData);
            elseif column == 2
                comp.updateratiocolumn(row, event.NewData);
            elseif column == 3
                comp.updateratio2column(row, event.NewData);
            elseif column == 4
            elseif column == 5
            elseif column == 6
                comp.updatesymbolcolumn(row, event.NewData);
            elseif column == 7'
                comp.rangedCheckboxClicked(row, event.NewData);
            end
            comp.updatetabledisplay;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(comp, event)
            if comp.hasNameWhitelist
                newName = comp.nameWhitelist(1);
            else
                newName = comp.nameWhitelist(1) + " " + (comp.nRows + 1);
            end

            if isempty(comp.ratioColumn)
                newRatio = 1;
            else
                newRatio = sum(comp.ratioColumn) / (comp.nRows);
            end

            newSymbol = "%";

            addrow(comp, newName, newRatio, newSymbol);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(comp, event)
            removerow(comp);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(comp, event)
            moveuprow(comp);
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(comp, event)
            movedownrow(comp);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(comp, event)
            applychanges(comp);
        end

        % Button pushed function: RejectButton
        function RejectButtonPushed(comp, event)
            rejectchanges(comp);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 587 542];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UITable = uitable(comp.GridLayout);
            comp.UITable.ColumnName = {'Name'; 'Ratio'; 'Fraction'; 'Unit'};
            comp.UITable.RowName = {};
            comp.UITable.SelectionType = 'row';
            comp.UITable.ColumnEditable = [true true false true];
            comp.UITable.CellEditCallback = matlab.apps.createCallbackFcn(comp, @UITableCellEdit, true);
            comp.RejectButton = uibutton(comp.GridLayout, 'push');
            comp.RejectButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RejectButtonPushed, true);
            comp.RejectButton.Text = '🗙';
            comp.ApplyButton = uibutton(comp.GridLayout, 'push');
            comp.ApplyButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ApplyButtonPushed, true);
            comp.ApplyButton.Text = '✔';
            comp.MoveDownButton = uibutton(comp.GridLayout, 'push');
            comp.MoveDownButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoveDownButtonPushed, true);
            comp.MoveDownButton.Text = '▼';
            comp.MoveUpButton = uibutton(comp.GridLayout, 'push');
            comp.MoveUpButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MoveUpButtonPushed, true);
            comp.MoveUpButton.Text = '▲';
            comp.RemoveButton = uibutton(comp.GridLayout, 'push');
            comp.RemoveButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RemoveButtonPushed, true);
            comp.RemoveButton.Text = '−';
            comp.AddButton = uibutton(comp.GridLayout, 'push');
            comp.AddButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @AddButtonPushed, true);
            comp.AddButton.Text = '+';
            comp.Lamp = uilamp(comp.GridLayout);
            comp.ChangesStatusLabel = uilabel(comp.GridLayout);
            comp.ChangesStatusLabel.Text = 'No new changes to apply';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end