classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        NewMenu_2                      matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        ImportMenu                     matlab.ui.container.Menu
        PropellantMenu                 matlab.ui.container.Menu
        PropellantmixtureMenu          matlab.ui.container.Menu
        EnginedefinitionMenu           matlab.ui.container.Menu
        AtmosphericcompositionMenu     matlab.ui.container.Menu
        UnitdefinitionsMenu            matlab.ui.container.Menu
        ExportMenu                     matlab.ui.container.Menu
        PropellantMenu_2               matlab.ui.container.Menu
        PropellantmixtureMenu_2        matlab.ui.container.Menu
        GeometrydefinitionsMenu_2      matlab.ui.container.Menu
        AtmosphericcompositionMenu_2   matlab.ui.container.Menu
        UnitdefinitionsMenu_2          matlab.ui.container.Menu
        UnitsMenu                      matlab.ui.container.Menu
        LengthMenu                     matlab.ui.container.Menu
        mMenu                          matlab.ui.container.Menu
        mmMenu                         matlab.ui.container.Menu
        inMenu                         matlab.ui.container.Menu
        ftMenu                         matlab.ui.container.Menu
        MassMenu                       matlab.ui.container.Menu
        kgMenu                         matlab.ui.container.Menu
        gMenu                          matlab.ui.container.Menu
        tMenu                          matlab.ui.container.Menu
        lbMenu                         matlab.ui.container.Menu
        SpeedMenu                      matlab.ui.container.Menu
        msMenu                         matlab.ui.container.Menu
        kmhMenu                        matlab.ui.container.Menu
        ftsMenu                        matlab.ui.container.Menu
        imhMenu                        matlab.ui.container.Menu
        ForceMenu                      matlab.ui.container.Menu
        PressureMenu                   matlab.ui.container.Menu
        TemperatureMenu                matlab.ui.container.Menu
        EnergyMenu                     matlab.ui.container.Menu
        PowerMenu                      matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        OutputsPanel                   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        SummaryTab                     matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        GridLayout13                   matlab.ui.container.GridLayout
        SpecificImpulsevacEditField    matlab.ui.control.NumericEditField
        SpecificImpulsevacEditFieldLabel  matlab.ui.control.Label
        MassflowEditField              matlab.ui.control.NumericEditField
        MassflowunitareaatthethroatEditFieldLabel  matlab.ui.control.Label
        SpecificImpulseEditField       matlab.ui.control.NumericEditField
        SpecificImpulseEditFieldLabel  matlab.ui.control.Label
        GridLayout10_3                 matlab.ui.container.GridLayout
        SummaryTable                   matlab.ui.control.Table
        DetailedTab                    matlab.ui.container.Tab
        GraphingTab                    matlab.ui.container.Tab
        ErrorsTab                      matlab.ui.container.Tab
        ExecuteButton                  matlab.ui.control.Button
        PropellantsPanel               matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        SpeciesTable                   matlab.ui.control.Table
        GridLayout9                    matlab.ui.container.GridLayout
        ConfirmSpeciesButton           matlab.ui.control.Button
        MoveUpSpeciesButton            matlab.ui.control.Button
        MoveDownSpeciesButton          matlab.ui.control.Button
        RemoveSpeciesButton_2          matlab.ui.control.Button
        AddSpeciesButton               matlab.ui.control.Button
        PropellantLabel                matlab.ui.control.Label
        GridLayout10                   matlab.ui.container.GridLayout
        MixturecompositionLabel        matlab.ui.control.Label
        GridLayout8                    matlab.ui.container.GridLayout
        AddPropButton                  matlab.ui.control.Button
        MoveDownPropButton             matlab.ui.control.Button
        MoveUpPropButton               matlab.ui.control.Button
        RemovePropButton               matlab.ui.control.Button
        PropTable                      matlab.ui.control.Table
        ProbingPointsPanel             matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        GridLayout10_2                 matlab.ui.container.GridLayout
        ProbingpointsLabel             matlab.ui.control.Label
        GridLayout8_2                  matlab.ui.container.GridLayout
        AddPropButton_2                matlab.ui.control.Button
        MoveDownPropButton_2           matlab.ui.control.Button
        MoveUpPropButton_2             matlab.ui.control.Button
        RemovePropButton_2             matlab.ui.control.Button
        PropTable_2                    matlab.ui.control.Table
        EngineDefinitionsPanel         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout6_4                  matlab.ui.container.GridLayout
        AtmoTemperatureEditField       matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        AtmoPressureEditField          matlab.ui.control.NumericEditField
        PressureEditFieldLabel         matlab.ui.control.Label
        AtmosphericInfoLabel           matlab.ui.control.Label
        AtmoTemperatureUnitLabel       matlab.ui.control.Label
        AtmoPressureUnitLabel          matlab.ui.control.Label
        GridLayout6_5                  matlab.ui.container.GridLayout
        InjectionPressureUnitLabel     matlab.ui.control.Label
        InjectionTemperatureUnitLabel  matlab.ui.control.Label
        InjectionPressureEditField     matlab.ui.control.NumericEditField
        PressureEditField_2Label_2     matlab.ui.control.Label
        InjectionTemperatureEditField  matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel_2    matlab.ui.control.Label
        InjectionDefinitionsLabel      matlab.ui.control.Label
        GridLayout6_3                  matlab.ui.container.GridLayout
        HeatingElementOutputUnitLabel  matlab.ui.control.Label
        HeatingElementOutputEditField  matlab.ui.control.NumericEditField
        HeatingElementOutputEditFieldLabel  matlab.ui.control.Label
        ChamberDefinitionsLabel        matlab.ui.control.Label
        GridLayout6                    matlab.ui.container.GridLayout
        ContractionRatioEditField      matlab.ui.control.NumericEditField
        ContractionratioLabel          matlab.ui.control.Label
        ExpansionRatioEditField        matlab.ui.control.NumericEditField
        ExpansionratioLabel            matlab.ui.control.Label
        AreaRatioesLabel               matlab.ui.control.Label
        ContextMenu                    matlab.ui.container.ContextMenu
        NewMenu                        matlab.ui.container.Menu
        PredefinedMenu                 matlab.ui.container.Menu
        Menu                           matlab.ui.container.Menu
    end

    properties (Access = private)

        % Imported data
        phaseImported;
        engineImported;
        unitsImported;

        % Phase
        chosenPhase = 'R1highT.yaml'

        % Geometry definitions
        expansionRatio = 10;
        contractionRatio = 5;

        %Injection definitions
        temperature_in = 293;
        pressure_in = 100e5;

        %Chamber definitions
        heatPower = 0;

        % Atmospheric conditions
        temperature_at = 293;
        pressure_at = oneatm;
        atmo = GRI30;

        % Propellants
        props = Solution('R1highT.yaml');
        propsRatio = 1;
        propsRenominator = 1;
        propsIndex = 1;

        % Outputs
        propMixture;
        probingPoint_converging;
        probingPoint_diverging;

        % Propellant species temporary data
        speciesName = string.empty;
        speciesRatio = double.empty;

        % States
        s_atmo = State();
        s_injection = State();
        s_chamber = State();
        s_stagnation = State();
        s_throat = State();
        s_exit = State();
        s_probes = State();

        % Unit definitiions
        UnitDialog;
        unit = struct('length', 0, 'mass', 0, 'speed', 0, 'force', 0, 'pressure', 0, 'temperature', 0, 'energy', 0, 'power', 0);

        unit_length_id = 1;

        unit_length = [1, 1e-3, 0.0254, 0.3048];
        unit_length_name = ["m", "mm", "in", "ft"];

        unit_mass_id = 1;

        unit_mass = [1, 1e-3, 1e3, 0.45359237];
        unit_mass_name = ["kg", "g", "t", "lb"];

        unit_speed_id = 1;

        unit_speed = [1, 1/3.6, 0.3408, 0.44704];
        unit_speed_name = ["m/s", "km/h", "ft/s", "mi/h"];

        unit_force_id = 1;

        unit_force = [1, 1e3, 9.81e3, 4.44822162];
        unit_force_name = ["N", "kN", "tf", "lbf"];

        unit_pressure_id = 1;

        unit_pressure = [1, 1e3, 1e6, 1e5, 101325, 6894.75729];
        unit_pressure_name = ["Pa", "kPa", "MPa", "bar", "atm", "psi"];

        unit_temperature_id = 1;

        unit_temperature_muliplier = [1, 1, 5/9, 5/9];
        unit_temperature_offset = [0, -273.15, -459.67 * 5 / 9, 0];
        unit_temperature_name = ["K", "°C", "°F", "°R"];

        unit_energy_id = 1;

        unit_energy = [1, 1e3, 4.18400, 4.184e3];
        unit_energy_name = ["J", "kJ", "cal", "kcal"];

        unit_power_id = 1;

        unit_power = [1, 1e3, 746];
        unit_power_name = ["W", "kW", "hp"];

        use_molar_weight = true;

    end

    methods (Access = private)

        % Calculates an area of a circle
        function circleArea = circlearea(~, circleDiameter)
            circleArea = circleDiameter ^ 2 / 4 * pi;
        end

        % Calculates a diameter of a circle
        function circleDiameter = circlediameter(~, circleArea)
            circleDiameter = sqrt(circleArea / pi) * 2;
        end

        % Resets all displays
        function refreshdisp_value(app)
            app.ExpansionRatioEditField.Value = app.expansionRatio;
            app.ContractionRatioEditField.Value = app.contractionRatio;
            app.AtmoPressureEditField.Value = app.pressure_at / app.unit_pressure(app.unit_pressure_id);
            app.AtmoPressureUnitLabel.Text = app.unit_pressure_name(app.unit_pressure_id);
            app.AtmoTemperatureEditField.Value = (app.temperature_at + app.unit_temperature_offset(app.unit_temperature_id)) / app.unit_temperature_muliplier(app.unit_temperature_id);
            app.AtmoTemperatureUnitLabel.Text = app.unit_temperature_name(app.unit_temperature_id);
            app.InjectionPressureEditField.Value = app.pressure_in / app.unit_pressure(app.unit_pressure_id);
            app.InjectionPressureUnitLabel.Text = app.unit_pressure_name(app.unit_pressure_id);
            app.InjectionTemperatureEditField.Value = (app.temperature_in + app.unit_temperature_offset(app.unit_temperature_id)) / app.unit_temperature_muliplier(app.unit_temperature_id);
            app.InjectionTemperatureUnitLabel.Text = app.unit_temperature_name(app.unit_temperature_id);
            app.HeatingElementOutputEditField.Value = app.heatPower / app.unit_power(app.unit_power_id);
            app.HeatingElementOutputUnitLabel.Text = app.unit_power_name(app.unit_power_id);
        end

        function refreshdisp_propTable(app)
            Table = table();
            for i = 1 : size(app.props, 2)
                Table(i, 1) = table(string(name(app.props(i))));
                Table(i, 2) = table(app.propsRatio(i));
            end
            app.PropTable.Data = Table;
        end

        function regendata_speciesTable(app)
            index = app.propsIndex;
            nZeros = 0;
            app.speciesName = string(zeros(1, 1));
            app.speciesRatio = zeros(1, 1);
            for i = 1 : nSpecies(app.props(index))
                massFraction_temp = massFraction(app.props(index), speciesName(app.props(index), i));
                if massFraction_temp > 0
                    app.speciesName(i - nZeros) = string(speciesName(app.props(index), i));
                    app.speciesRatio(i - nZeros) = massFraction_temp * app.propsRenominator(index);
                else
                    nZeros = nZeros + 1;
                end
            end
        end

        function refreshdisp_speciesTable(app)
            Table = table();
            for i = 1 : size(app.speciesName, 2)
                Table(i, 1) = table(app.speciesName(i));
                Table(i, 2) = table(app.speciesRatio(i));
            end
            app.SpeciesTable.Data = Table;
        end


        function atmoreset(app)
            set(app.atmo, 'X', 'N2:78.084,O2:20.946,AR:0.9340,CO2:0.0417', 'P', oneatm, 'T', 293);
        end

        function phase = phasecopy(app, phase_original)
            phase_copy = Solution('R1highT.yaml');
            set(phase_copy, 'T', temperature(phase_original), 'P', pressure(phase_original), 'X', moleFractions(phase_original));
            phase = phase_copy;
        end

        function phase = phasecombine(~, phases_original, ratioes)
            phase = Solution('R1highT.yaml');
            pressure_partial = zeros([size(phases_original, 2), 1], "double");
            mass_fractions_new = zeros([nSpecies(phases_original(1)), 1], "double");
            internal_energy = 0;
            for i = 1 : size(phases_original, 2)
                mass_fractions_new = mass_fractions_new + massFractions(phases_original(i)) * ratioes(i) * pressure(phases_original(i));
                pressure_partial(i) = pressure(phases_original(i)) * ratioes(i) / sum(ratioes);
            end
            pressure_total = sum(pressure_partial);
            for i = 1 : size(phases_original, 2)
                internal_energy = internal_energy + intEnergy_mass(phases_original(i)) * pressure_partial(i) / pressure_total;
                temperature_props(i) = temperature(phases_original(i));
            end
            setMassFractions(phase, mass_fractions_new);
            temperature_min = min(temperature_props);
            temperature_max = max(temperature_props);
            while 1
                temperature_final = (temperature_min + temperature_max) / 2;
                set(phase, 'P', pressure_total, 'T', temperature_final);
                internal_energy_calc = intEnergy_mass(phase);
                error = (internal_energy_calc - internal_energy) / abs(internal_energy_calc);
                if abs(error) < 1e-6
                    break;
                end
                if error < 0
                    temperature_min = temperature_final;
                else
                    temperature_max = temperature_final;
                end

            end
            phase
        end

        function phase = importphase(source)

        end

        function importengine(source)

        end
        
        function regendata_summaryTable(app)
            import State.*
            properties = ["Temperature", "Pressure", "Density", "A/A*", "Velocity", "Mach Number"];
            states = [app.s_injection, app.s_chamber, app.s_throat, app.s_exit];
            states
            if isempty(app.s_injection)
            for i = 1 : 4
                states(i) = State();
            end
            end

            Table = table();
            for i = 1 : 6
                Table(i, 1) = table(properties(i));
            end

            % Temparature
            for i = 1 : 4
                Table(1, i + 1) = table(states(i).temperature);
            end

            % Pressure
            for i = 1 : 4
                Table(2, i + 1) = table(states(i).pressure);
            end

            % Density
            for i = 1 : 4
                Table(3, i + 1) = table(states(i).density);
            end

            % A/A*
            Table(4, 2) = {app.contractionRatio};
            Table(4, 3) = {app.contractionRatio};
            Table(4, 4) = {1};
            Table(4, 5) = {app.expansionRatio};

            % Velocity
            for i = 1 : 4
                Table(5, i + 1) = table(states(i).velocity);
            end

            % Mach Number
            for i = 1 : 4
                Table(6, i + 1) = table(Mach(states(i), states(i).velocity));
            end
            app.SummaryTable.Data = Table;
            
        end
        
        function results = read_units(app)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app, arg1)
            app.refreshdisp_propTable();
            app.atmoreset();
            app.refreshdisp_value();
            app.regendata_summaryTable();
        end

        % Value changed function: HeatingElementOutputEditField
        function set_heatpower(app, event)
            app.heatPower = app.HeatingElementOutputEditField.Value * app.unit_power(app.unit_power_id);
        end

        % Button pushed function: AddPropButton
        function add_prop(app, event)
            app.props(size(app.props, 2) + 1) = Solution('R1highT.yaml');
            setName(app.props(size(app.props, 2)), convertStringsToChars("Propellant" + size(app.props, 2)));
            set(app.props(size(app.props, 2)), 'P', app.pressure_in, 'T', app.temperature_in);
            app.propsRatio(size(app.props, 2)) = 1;
            app.propsRenominator(size(app.props, 2)) = 1;
            app.refreshdisp_propTable();
        end

        % Button pushed function: RemovePropButton
        function remove_prop(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 1 : size(app.props, 2))
                index = app.PropTable.Selection(1);
            else
                return;
            end
            if app.PropTable.Selection(1) == size(app.props, 2) && size(app.props, 2) ~= 1
                app.PropTable.Selection(1) = app.PropTable.Selection(1) - 1;
            end
            app.props(index) = [];
            app.propsRatio(index) = [];
            app.propsRenominator(index) = [];
            app.refreshdisp_propTable();
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            end
            app.propsIndex = app.PropTable.Selection(1);
            app.regendata_speciesTable();
            app.refreshdisp_speciesTable();
        end

        % Cell selection callback: PropTable
        function select_prop(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 1 : size(app.props, 2))
                index = app.PropTable.Selection(1);
            else
                return;
            end
            app.PropellantLabel.Text = string(name(app.props(index)));
            app.propsIndex = app.PropTable.Selection(1);
            app.regendata_speciesTable();
            app.refreshdisp_speciesTable();
        end

        % Button pushed function: MoveUpPropButton
        function moveup_prop(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 2 : size(app.props, 2))
                index = app.PropTable.Selection(1);
            else
                return;
            end
            if app.PropTable.Selection(1) ~= 1 && size(app.props, 2) ~= 1
                app.PropTable.Selection(1) = app.PropTable.Selection(1) - 1;
            end
            app.props([index; index - 1]) = app.props([index - 1; index]);
            app.propsRatio([index; index - 1]) = app.propsRatio([index - 1; index]);
            app.propsRenominator([index; index - 1]) = app.propsRenominator([index - 1; index]);
            app.refreshdisp_propTable();
            app.propsIndex = app.PropTable.Selection(1);
            app.regendata_speciesTable();
            app.refreshdisp_speciesTable();
        end

        % Button pushed function: MoveDownPropButton
        function movedown_prop(app, event)
            if size(app.PropTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.PropTable.Selection(1), 1 : size(app.props, 2) - 1)
                index = app.PropTable.Selection(1);
            else
                return;
            end
            if app.PropTable.Selection(1) ~= size(app.props, 2) && size(app.props, 2) ~= 1
                app.PropTable.Selection(1) = app.PropTable.Selection(1) + 1;
            end
            app.props([index; index + 1]) = app.props([index + 1; index]);
            app.propsRatio([index; index + 1]) = app.propsRatio([index + 1; index]);
            app.propsRenominator([index; index + 1]) = app.propsRenominator([index + 1; index]);
            app.refreshdisp_propTable();
            app.propsIndex = app.PropTable.Selection(1);
            app.regendata_speciesTable();
            app.refreshdisp_speciesTable();
        end

        % Cell edit callback: PropTable
        function edit_prop(app, event)
            indices = event.Indices;
            index = indices(1);
            type = indices(2);
            newData = event.NewData;
            if type == 1
                setName(app.props(index), convertStringsToChars(newData));
            else
                if ~isnan(double(newData))
                    app.propsRatio(index) = double(newData);
                end
            end
            app.refreshdisp_propTable();
        end

        % Button pushed function: AddSpeciesButton
        function add_species(app, event)
            app.speciesName(size(app.speciesName, 2) + 1) = 'H2';
            app.speciesRatio(size(app.speciesName, 2) + 1) = 0;
            app.refreshdisp_speciesTable();
        end

        % Button pushed function: RemoveSpeciesButton_2
        function remove_species(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 1 : size(app.speciesName, 2))
                index = app.SpeciesTable.Selection(1);
            else
                return;
            end
            if app.SpeciesTable.Selection(1) == size(app.speciesName, 2) && size(app.speciesName, 2) ~= 1
                app.SpeciesTable.Selection(1) = app.SpeciesTable.Selection(1) - 1;
            end
            app.speciesName(index) = [];
            app.speciesRatio(index) = [];
            app.refreshdisp_speciesTable();
        end

        % Button pushed function: MoveUpSpeciesButton
        function moveup_species(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 2 : size(app.speciesName, 2))
                index = app.SpeciesTable.Selection(1);
            else
                return;
            end
            if app.SpeciesTable.Selection(1) ~= 1 && size(app.speciesName, 2) ~= 1
                app.SpeciesTable.Selection(1) = app.SpeciesTable.Selection(1) - 1;
            end
            app.speciesName([index; index - 1]) = app.speciesName([index - 1; index]);
            app.speciesRatio([index; index - 1]) =  app.speciesRatio([index - 1; index]);
            app.refreshdisp_speciesTable();
        end

        % Button pushed function: MoveDownSpeciesButton
        function movedown_species(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 1 : size(app.speciesName, 2) - 1)
                index = app.SpeciesTable.Selection(1);
            else
                return;
            end
            if app.SpeciesTable.Selection(1) ~= size(app.speciesName, 2) && size(app.speciesName, 2) ~= 1
                app.SpeciesTable.Selection(1) = app.SpeciesTable.Selection(1) + 1;
            end
            app.speciesName([index; index + 1]) = app.speciesName([index + 1; index]);
            app.speciesRatio([index; index + 1]) =  app.speciesRatio([index + 1; index]);
            app.refreshdisp_speciesTable();
        end

        % Cell edit callback: SpeciesTable
        function edit_species(app, event)
            indices = event.Indices;
            index = indices(1);
            type = indices(2);
            newData = event.NewData;
            if type == 1
                if ismember(newData, speciesName(app.props(app.propsIndex), 1 : nSpecies(app.props(app.propsIndex))))
                    app.speciesName(index) = newData;
                end
            else
                if ~isnan(double(newData))
                    app.speciesRatio(index) = newData;
                end
            end
            app.refreshdisp_speciesTable();
        end

        % Cell selection callback: SpeciesTable
        function select_species(app, event)
            if size(app.SpeciesTable.Selection, 1) ~= 1
                return;
            elseif ismember(app.SpeciesTable.Selection(1), 1 : size(app.props, 2))
            else
                return;
            end
            app.refreshdisp_speciesTable();
        end

        % Button pushed function: ConfirmSpeciesButton
        function confirm_species(app, event)
            ratioes = zeros(nSpecies(app.props(app.propsIndex)), 1, 'double');
            for i = 1 : size(app.speciesName, 2)
                ratioes(speciesIndex(app.props(app.propsIndex), char(app.speciesName(i)))) = ratioes(speciesIndex(app.props(app.propsIndex), char(app.speciesName(i)))) + app.speciesRatio(i);
            end
            app.propsRenominator(app.propsIndex) = sum(ratioes);
            set(app.props(app.propsIndex), 'Y', ratioes, 'T', app.temperature_in, 'P', app.pressure_in);
            app.regendata_speciesTable();
            app.refreshdisp_speciesTable();
        end

        % Value changed function: AtmoPressureEditField
        function set_pressure_at(app, event)
            app.pressure_at = app.AtmoPressureEditField.Value;
            set(app.atmo, 'T', app.temperature_at, 'P', app.pressure_at);
        end

        % Value changed function: AtmoTemperatureEditField
        function set_temperature_at(app, event)
            app.temperature_at = app.AtmoTemperatureEditField.Value;
            set(atmo, 'T', app.temperature_at, 'P', app.pressure_at);
        end

        % Button pushed function: ExecuteButton
        function execute(app, event)
            import State.*
            clc
            % Constraints
            geometry_defined = false;
            expansion_ratio = app.expansionRatio;
            contraction_ratio = app.contractionRatio;
            heat_power = app.heatPower;
            thrust = 1.81e6;

            % Atmospheric conditions
            s_atmo = State(app.atmo);

            % Initial Injection
            %prop = Solution('R1highT.yaml');
            prop = app.phasecombine(app.props, app.propsRatio);
            s_injection = State(prop);
            s_injection.pressure = app.pressure_in;
            s_injection.temperature = app.temperature_in;
            injection(prop, s_injection);


            % Initial Combustion
            combustion(prop);
            s_chamber = State(prop);


            % Initial stagnation state definition
            s_stagnation = State(s_chamber);

            % Throat conditions and re-evaluation of initial and combustion states
            s_throat = State();
            mass_rate_area_old = 0;
            for i = 1 : 20
                pressure_max = s_stagnation.pressure * (2 / (k(s_stagnation) + 1)) ^ ((k(s_stagnation)) / (k(s_stagnation) - 1)) * 1.5;
                pressure_min = s_stagnation.pressure / 1.5 ^ 2;
                for j = 1 : 20
                    s_throat.pressure = (pressure_min + pressure_max) / 2;
                    set(prop, 'P', s_throat.pressure, 'S', s_stagnation.entropy);
                    s_throat = State(prop);
                    s_throat.velocity = sqrt(2 * (s_stagnation.enthalpy - s_throat.enthalpy));
                    error_V = (s_throat.velocity - s_throat.soundspeed) / s_throat.velocity;
                    if abs(error_V) < 1e-6 % Checking for convergence
                        break;
                    end
                    if error_V > 0 % Range redefinition
                        pressure_min = s_throat.pressure;
                    else
                        pressure_max = s_throat.pressure;
                    end
                end
                % Reevaluate post-combustion state and stagnation state if mass flow
                % rate convergence (steady state condition) is not met.

                mass_rate_area = s_throat.velocity * s_throat.density;
                error_M = (mass_rate_area_old - mass_rate_area) / mass_rate_area_old;
                if abs(error_M) > 1e-6
                    injection(prop, s_injection);
                    combustion(prop, heat_power, mass_rate_area);
                    s_chamber = State(prop);
                    s_chamber.velocity = mass_rate_area / (contraction_ratio * s_chamber.density);
                    s_stagnation.enthalpy = s_chamber.enthalpy - s_chamber.velocity ^ 2 / 2;
                    setEnthalpyIsentropic(prop, s_stagnation.enthalpy);
                    s_stagnation = State(prop);
                    s_chamber.velocity = sqrt(2 * (s_chamber.enthalpy - s_stagnation.enthalpy));
                else
                    if abs(error_V) < 1e-6  % Checking for convergence
                        break;
                    end
                end
                mass_rate_area_old = mass_rate_area;
            end
            % Bisection method, used to find the exit state. Temperature is used as a
            % variable and is known to fall between absolute zero and throat
            % temperature. Mass flow rate is used to checked for convergence to satisfy
            % conservation of mass.
            s_exit = State();
            for i = 1 : 30
                temperature_min = 0;
                temperature_max = s_throat.temperature;
                for j = 1 : 20
                    s_exit.temperature = (temperature_min + temperature_max) / 2; % Bisection midpoint
                    % Cantera hasn't implemented a function to change temperature at
                    % constant entropy. I made my own function to faciliate that.
                    setTemperatureIsentropic(prop, s_exit.temperature);
                    s_exit = State(prop);
                    s_exit.velocity = sqrt(2 * (s_stagnation.enthalpy - s_exit.enthalpy));
                    mass_rate_area_calc = expansion_ratio * s_exit.velocity * s_exit.density;
                    error_M = (mass_rate_area_calc - mass_rate_area) / mass_rate_area_calc;
                    if abs(error_M) < 1e-6  % Checking for convergence
                        break;
                    end
                    if error_M < 0 % Range redefinition
                        temperature_min = s_exit.temperature;
                    else
                        temperature_max = s_exit.temperature;
                    end
                end

            end
            energy_cc = s_chamber.enthalpy + s_chamber.velocity ^ 2 / 2;
            energy_th = s_throat.enthalpy + s_throat.velocity ^ 2 / 2;
            energy_ex = s_exit.enthalpy + s_exit.velocity ^ 2 / 2;
            specific_impulse = (s_exit.velocity + (s_exit.pressure - s_atmo.pressure) * expansion_ratio / mass_rate_area) / 9.81
            specific_impulse_vac = (s_exit.velocity + (s_exit.pressure - 0) * expansion_ratio / mass_rate_area) / 9.81
            app.s_injection = State(s_injection);
            app.s_chamber = State(s_chamber);
            app.s_throat = State(s_throat);
            app.s_exit = State(s_exit);

            app.regendata_summaryTable();
            app.MassflowEditField.Value = mass_rate_area;
            app.SpecificImpulseEditField.Value = specific_impulse;
            app.SpecificImpulsevacEditField.Value = specific_impulse_vac;
        end

        % Value changed function: InjectionPressureEditField
        function set_pressure_in(app, event)
            app.pressure_in = app.InjectionPressureEditField.Value;
        end

        % Value changed function: InjectionTemperatureEditField
        function set_temperature_in(app, event)
            app.temperature_in = app.InjectionTemperatureEditField.Value;
        end

        % Value changed function: ExpansionRatioEditField
        function set_expansionRatio(app, event)
            app.expansionRatio = app.ExpansionRatioEditField.Value;
            if app.expansionRatio < 1
                app.expansionRatio = 1
            end
        end

        % Value changed function: ContractionRatioEditField
        function set_contractionRatio(app, event)
            app.contractionRatio = app.ContractionRatioEditField.Value;
            if app.contractionRatio < 1
                app.contractionRatio = 1
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu_2 = uimenu(app.FileMenu);
            app.NewMenu_2.Text = 'New';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.PropellantMenu = uimenu(app.ImportMenu);
            app.PropellantMenu.Text = 'Propellant';
            app.PropellantmixtureMenu = uimenu(app.ImportMenu);
            app.PropellantmixtureMenu.Text = 'Propellant mixture';
            app.EnginedefinitionMenu = uimenu(app.ImportMenu);
            app.EnginedefinitionMenu.Text = 'Engine definition';
            app.AtmosphericcompositionMenu = uimenu(app.ImportMenu);
            app.AtmosphericcompositionMenu.Text = 'Atmospheric composition';
            app.UnitdefinitionsMenu = uimenu(app.ImportMenu);
            app.UnitdefinitionsMenu.Text = 'Unit definitions';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.PropellantMenu_2 = uimenu(app.ExportMenu);
            app.PropellantMenu_2.Text = 'Propellant';
            app.PropellantmixtureMenu_2 = uimenu(app.ExportMenu);
            app.PropellantmixtureMenu_2.Text = 'Propellant mixture';
            app.GeometrydefinitionsMenu_2 = uimenu(app.ExportMenu);
            app.GeometrydefinitionsMenu_2.Text = 'Geometry definitions';
            app.AtmosphericcompositionMenu_2 = uimenu(app.ExportMenu);
            app.AtmosphericcompositionMenu_2.Text = 'Atmospheric composition';
            app.UnitdefinitionsMenu_2 = uimenu(app.ExportMenu);
            app.UnitdefinitionsMenu_2.Text = 'Unit definitions';
            app.UnitsMenu = uimenu(app.UIFigure);
            app.UnitsMenu.Text = 'Units';
            app.LengthMenu = uimenu(app.UnitsMenu);
            app.LengthMenu.Text = 'Length';
            app.mMenu = uimenu(app.LengthMenu);
            app.mMenu.Text = 'm';
            app.mmMenu = uimenu(app.LengthMenu);
            app.mmMenu.Text = 'mm';
            app.inMenu = uimenu(app.LengthMenu);
            app.inMenu.Text = 'in';
            app.ftMenu = uimenu(app.LengthMenu);
            app.ftMenu.Text = 'ft';
            app.MassMenu = uimenu(app.UnitsMenu);
            app.MassMenu.Text = 'Mass';
            app.kgMenu = uimenu(app.MassMenu);
            app.kgMenu.Text = 'kg';
            app.gMenu = uimenu(app.MassMenu);
            app.gMenu.Text = 'g';
            app.tMenu = uimenu(app.MassMenu);
            app.tMenu.Text = 't';
            app.lbMenu = uimenu(app.MassMenu);
            app.lbMenu.Text = 'lb';
            app.SpeedMenu = uimenu(app.UnitsMenu);
            app.SpeedMenu.Text = 'Speed';
            app.msMenu = uimenu(app.SpeedMenu);
            app.msMenu.Text = 'm/s';
            app.kmhMenu = uimenu(app.SpeedMenu);
            app.kmhMenu.Text = 'km/h';
            app.ftsMenu = uimenu(app.SpeedMenu);
            app.ftsMenu.Text = 'ft/s';
            app.imhMenu = uimenu(app.SpeedMenu);
            app.imhMenu.Text = 'im/h';
            app.ForceMenu = uimenu(app.UnitsMenu);
            app.ForceMenu.Text = 'Force';
            app.PressureMenu = uimenu(app.UnitsMenu);
            app.PressureMenu.Text = 'Pressure';
            app.TemperatureMenu = uimenu(app.UnitsMenu);
            app.TemperatureMenu.Text = 'Temperature';
            app.EnergyMenu = uimenu(app.UnitsMenu);
            app.EnergyMenu.Text = 'Energy';
            app.PowerMenu = uimenu(app.UnitsMenu);
            app.PowerMenu.Text = 'Power';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EngineDefinitionsPanel = uipanel(app.GridLayout);
            app.EngineDefinitionsPanel.Title = 'Engine Definitions';
            app.GridLayout2 = uigridlayout(app.EngineDefinitionsPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.AreaRatioesLabel = uilabel(app.GridLayout6);
            app.AreaRatioesLabel.Text = 'Area Ratioes';
            app.ExpansionratioLabel = uilabel(app.GridLayout6);
            app.ExpansionratioLabel.Text = 'Expansion Ratio';
            app.ExpansionRatioEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ExpansionRatioEditField.ValueChangedFcn = createCallbackFcn(app, @set_expansionRatio, true);
            app.ContractionratioLabel = uilabel(app.GridLayout6);
            app.ContractionratioLabel.Text = 'Contraction Ratio';
            app.ContractionRatioEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ContractionRatioEditField.ValueChangedFcn = createCallbackFcn(app, @set_contractionRatio, true);
            app.GridLayout6_3 = uigridlayout(app.GridLayout2);
            app.ChamberDefinitionsLabel = uilabel(app.GridLayout6_3);
            app.ChamberDefinitionsLabel.Text = 'Chamber Definitions';
            app.HeatingElementOutputEditFieldLabel = uilabel(app.GridLayout6_3);
            app.HeatingElementOutputEditFieldLabel.Text = 'Heating Element Output';
            app.HeatingElementOutputEditField = uieditfield(app.GridLayout6_3, 'numeric');
            app.HeatingElementOutputEditField.ValueChangedFcn = createCallbackFcn(app, @set_heatpower, true);
            app.HeatingElementOutputUnitLabel = uilabel(app.GridLayout6_3);
            app.HeatingElementOutputUnitLabel.Text = 'W';
            app.GridLayout6_5 = uigridlayout(app.GridLayout2);
            app.InjectionDefinitionsLabel = uilabel(app.GridLayout6_5);
            app.InjectionDefinitionsLabel.Text = 'Injection Definitions';
            app.TemperatureEditFieldLabel_2 = uilabel(app.GridLayout6_5);
            app.TemperatureEditFieldLabel_2.Text = 'Temperature';
            app.InjectionTemperatureEditField = uieditfield(app.GridLayout6_5, 'numeric');
            app.InjectionTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @set_temperature_in, true);
            app.PressureEditField_2Label_2 = uilabel(app.GridLayout6_5);
            app.PressureEditField_2Label_2.Text = 'Pressure';
            app.InjectionPressureEditField = uieditfield(app.GridLayout6_5, 'numeric');
            app.InjectionPressureEditField.ValueChangedFcn = createCallbackFcn(app, @set_pressure_in, true);
            app.InjectionTemperatureUnitLabel = uilabel(app.GridLayout6_5);
            app.InjectionTemperatureUnitLabel.Text = 'K';
            app.InjectionPressureUnitLabel = uilabel(app.GridLayout6_5);
            app.InjectionPressureUnitLabel.Text = 'Pa';
            app.GridLayout6_4 = uigridlayout(app.GridLayout2);
            app.AtmoPressureUnitLabel = uilabel(app.GridLayout6_4);
            app.AtmoPressureUnitLabel.Text = 'Pa';
            app.AtmoTemperatureUnitLabel = uilabel(app.GridLayout6_4);
            app.AtmoTemperatureUnitLabel.Text = 'K';
            app.AtmosphericInfoLabel = uilabel(app.GridLayout6_4);
            app.AtmosphericInfoLabel.Text = 'Atmospheric Info';
            app.PressureEditFieldLabel = uilabel(app.GridLayout6_4);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.AtmoPressureEditField = uieditfield(app.GridLayout6_4, 'numeric');
            app.AtmoPressureEditField.ValueChangedFcn = createCallbackFcn(app, @set_pressure_at, true);
            app.TemperatureEditFieldLabel = uilabel(app.GridLayout6_4);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.AtmoTemperatureEditField = uieditfield(app.GridLayout6_4, 'numeric');
            app.AtmoTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @set_temperature_at, true);
            app.ProbingPointsPanel = uipanel(app.GridLayout);
            app.ProbingPointsPanel.Title = 'Probing Points';
            app.GridLayout2_2 = uigridlayout(app.ProbingPointsPanel);
            app.GridLayout10_2 = uigridlayout(app.GridLayout2_2);
            app.PropTable_2 = uitable(app.GridLayout10_2);
            app.PropTable_2.ColumnName = {'Name'; 'Expansion Ratio'};
            app.PropTable_2.RowName = {};
            app.PropTable_2.ColumnEditable = [true true true];
            app.PropTable_2.Multiselect = 'off';
            app.GridLayout8_2 = uigridlayout(app.GridLayout10_2);
            app.RemovePropButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.RemovePropButton_2.Text = 'Remove Point';
            app.MoveUpPropButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.MoveUpPropButton_2.Text = 'Move Up';
            app.MoveDownPropButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.MoveDownPropButton_2.Text = 'Move Down';
            app.AddPropButton_2 = uibutton(app.GridLayout8_2, 'push');
            app.AddPropButton_2.Text = 'Add New';
            app.ProbingpointsLabel = uilabel(app.GridLayout10_2);
            app.ProbingpointsLabel.Text = 'Probing points';
            app.PropellantsPanel = uipanel(app.GridLayout);
            app.PropellantsPanel.Title = 'Propellants';
            app.GridLayout3 = uigridlayout(app.PropellantsPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout3);
            app.PropTable = uitable(app.GridLayout10);
            app.PropTable.ColumnName = {'Propellant'; 'Mixture Ratio'};
            app.PropTable.RowName = {};
            app.PropTable.ColumnEditable = [true true true];
            app.PropTable.CellEditCallback = createCallbackFcn(app, @edit_prop, true);
            app.PropTable.CellSelectionCallback = createCallbackFcn(app, @select_prop, true);
            app.PropTable.Multiselect = 'off';
            app.GridLayout8 = uigridlayout(app.GridLayout10);
            app.RemovePropButton = uibutton(app.GridLayout8, 'push');
            app.RemovePropButton.ButtonPushedFcn = createCallbackFcn(app, @remove_prop, true);
            app.RemovePropButton.Text = 'Remove Propellant';
            app.MoveUpPropButton = uibutton(app.GridLayout8, 'push');
            app.MoveUpPropButton.ButtonPushedFcn = createCallbackFcn(app, @moveup_prop, true);
            app.MoveUpPropButton.Text = 'Move Up';
            app.MoveDownPropButton = uibutton(app.GridLayout8, 'push');
            app.MoveDownPropButton.ButtonPushedFcn = createCallbackFcn(app, @movedown_prop, true);
            app.MoveDownPropButton.Text = 'Move Down';
            app.AddPropButton = uibutton(app.GridLayout8, 'push');
            app.AddPropButton.ButtonPushedFcn = createCallbackFcn(app, @add_prop, true);
            app.AddPropButton.Text = 'Add New';
            app.MixturecompositionLabel = uilabel(app.GridLayout10);
            app.MixturecompositionLabel.Text = 'Mixture composition';
            app.GridLayout11 = uigridlayout(app.GridLayout3);
            app.PropellantLabel = uilabel(app.GridLayout11);
            app.PropellantLabel.Text = 'Propellant';
            app.GridLayout9 = uigridlayout(app.GridLayout11);
            app.AddSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.AddSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @add_species, true);
            app.AddSpeciesButton.Text = 'Add Species';
            app.RemoveSpeciesButton_2 = uibutton(app.GridLayout9, 'push');
            app.RemoveSpeciesButton_2.ButtonPushedFcn = createCallbackFcn(app, @remove_species, true);
            app.RemoveSpeciesButton_2.Text = 'Remove Species';
            app.MoveDownSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.MoveDownSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @movedown_species, true);
            app.MoveDownSpeciesButton.Text = 'Move Down';
            app.MoveUpSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.MoveUpSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @moveup_species, true);
            app.MoveUpSpeciesButton.Text = 'Move Up';
            app.ConfirmSpeciesButton = uibutton(app.GridLayout9, 'push');
            app.ConfirmSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @confirm_species, true);
            app.ConfirmSpeciesButton.Text = 'Confirm changes';
            app.SpeciesTable = uitable(app.GridLayout11);
            app.SpeciesTable.ColumnName = {'Species'; 'Mixture Ratio'};
            app.SpeciesTable.RowName = {};
            app.SpeciesTable.ColumnEditable = [true true];
            app.SpeciesTable.CellEditCallback = createCallbackFcn(app, @edit_species, true);
            app.SpeciesTable.CellSelectionCallback = createCallbackFcn(app, @select_species, true);
            app.SpeciesTable.Multiselect = 'off';
            app.OutputsPanel = uipanel(app.GridLayout);
            app.OutputsPanel.Title = 'Outputs';
            app.GridLayout4 = uigridlayout(app.OutputsPanel);
            app.ExecuteButton = uibutton(app.GridLayout4, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @execute, true);
            app.ExecuteButton.Text = 'Execute';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.GridLayout12 = uigridlayout(app.SummaryTab);
            app.GridLayout10_3 = uigridlayout(app.GridLayout12);
            app.SummaryTable = uitable(app.GridLayout10_3);
            app.SummaryTable.ColumnName = {'Property'; 'Injection'; 'Chamber Exit'; 'Throat'; 'Exit'};
            app.SummaryTable.RowName = {};
            app.SummaryTable.ColumnEditable = [true true true];
            app.SummaryTable.Multiselect = 'off';
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.SpecificImpulseEditFieldLabel = uilabel(app.GridLayout13);
            app.SpecificImpulseEditFieldLabel.Text = 'Specific Impulse';
            app.SpecificImpulseEditField = uieditfield(app.GridLayout13, 'numeric');
            app.SpecificImpulseEditField.Editable = 'off';
            app.MassflowunitareaatthethroatEditFieldLabel = uilabel(app.GridLayout13);
            app.MassflowunitareaatthethroatEditFieldLabel.Text = 'Mass flow / unit area at the throat';
            app.MassflowEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassflowEditField.Editable = 'off';
            app.SpecificImpulsevacEditFieldLabel = uilabel(app.GridLayout13);
            app.SpecificImpulsevacEditFieldLabel.Text = 'Specific Impulse (vac)';
            app.SpecificImpulsevacEditField = uieditfield(app.GridLayout13, 'numeric');
            app.SpecificImpulsevacEditField.Editable = 'off';
            app.DetailedTab = uitab(app.TabGroup);
            app.DetailedTab.Title = 'Detailed';
            app.GraphingTab = uitab(app.TabGroup);
            app.GraphingTab.Title = 'Graphing';
            app.ErrorsTab = uitab(app.TabGroup);
            app.ErrorsTab.Title = 'Errors';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.NewMenu = uimenu(app.ContextMenu);
            app.NewMenu.Text = 'New';
            app.PredefinedMenu = uimenu(app.ContextMenu);
            app.PredefinedMenu.Text = 'Pre-defined';
            app.Menu = uimenu(app.PredefinedMenu);
            app.Menu.Text = 'Menu';
            % Assign app.ContextMenu
            app.AddPropButton_2.ContextMenu = app.ContextMenu;
            app.AddPropButton.ContextMenu = app.ContextMenu;
            app.AddSpeciesButton.ContextMenu = app.ContextMenu;
            app.ConfirmSpeciesButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartUp(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end